classdef SST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        customizableEventsPanel      matlab.ui.container.Panel
        TabGroup2                    matlab.ui.container.TabGroup
        InflammationTab              matlab.ui.container.Tab
        inflammationSwitch           matlab.ui.control.Switch
        NumberofInflammedAxonsLabel  matlab.ui.control.Label
        numInflammedAxons            matlab.ui.control.NumericEditField
        TauDecayValuesLabel          matlab.ui.control.Label
        inflammationTau              matlab.ui.control.NumericEditField
        InflammationOnsetTimesLabel  matlab.ui.control.Label
        inflammationOnset            matlab.ui.control.NumericEditField
        EnableLabel                  matlab.ui.control.Label
        DisturbancesTab              matlab.ui.container.Tab
        disturbancesSwitch           matlab.ui.control.Switch
        MiscellaneousTab             matlab.ui.container.Tab
        miscellaneousSwitch          matlab.ui.control.Switch
        NumberofRecruitedAxonsLabel  matlab.ui.control.Label
        numRecruitedAxons            matlab.ui.control.NumericEditField
        NumberofDismissedAxonsLabel  matlab.ui.control.Label
        numDismissedAxons            matlab.ui.control.NumericEditField
        AxonsPanel                   matlab.ui.container.Panel
        NumberofAxonsLabel           matlab.ui.control.Label
        numAxonsField                matlab.ui.control.NumericEditField
        SamplingRateSamplessLabel    matlab.ui.control.Label
        samplingRateField            matlab.ui.control.NumericEditField
        SimulationTimesLabel         matlab.ui.control.Label
        simulationTimeField          matlab.ui.control.NumericEditField
        MaxPossibleSpikeRatespikessLabel  matlab.ui.control.Label
        maxSpikeRateField            matlab.ui.control.NumericEditField
        overlapCheckBox              matlab.ui.control.CheckBox
        repeatTemplateCheckBox       matlab.ui.control.CheckBox
        RecordingConditionsPanel     matlab.ui.container.Panel
        SignalToNoiseRatioLabel      matlab.ui.control.Label
        SNRField                     matlab.ui.control.NumericEditField
        addDriftCheckBox             matlab.ui.control.CheckBox
        addNoiseCheckBox             matlab.ui.control.CheckBox
        preNoiseCheckBox             matlab.ui.control.CheckBox
        LowbandcutoffHzLabel         matlab.ui.control.Label
        lowBandCutoffField           matlab.ui.control.NumericEditField
        HighbandcutoffHzLabel        matlab.ui.control.Label
        highBandCutoffField          matlab.ui.control.NumericEditField
        filterSignalButton           matlab.ui.control.StateButton
        RunButton                    matlab.ui.control.Button
        plotCheckBox                 matlab.ui.control.CheckBox
        saveSimulationButton         matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        MainSimulationTab            matlab.ui.container.Tab
        simulationUIAxes             matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        simulation % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Initialize input values
            data = struct;
            data.Naxons      = app.numAxonsField.Value;
            data.SNR         = app.SNRField.Value;
            data.total_time  = app.simulationTimeField.Value;
            data.fs          = app.samplingRateField.Value;
            data.sr          = randi(app.maxSpikeRateField.Value,1,data.Naxons);
            data.overlap     = app.overlapCheckBox.Value;
            data.rpt_temp    = app.repeatTemplateCheckBox.Value;
            data.has_drift   = app.addDriftCheckBox.Value;
            data.has_noise   = app.addNoiseCheckBox.Value;
            data.pre_noise   = app.preNoiseCheckBox.Value;
            data.do_filter   = app.filterSignalButton.Value;
            data.passband    = [app.lowBandCutoffField.Value, app.highBandCutoffField.Value];
            data.PLOT        = app.plotCheckBox.Value;
            
            % Run main simulator function
            app.simulation = SpikeSimulationTool(data);
            
            % Plot the resultant data in app (first figure)
            if ~app.plotCheckBox.Value
                plot(app.simulationUIAxes, app.simulation.time, app.simulation.data);
            end
            
        end

        % Value changed function: filterSignalButton
        function filterSignalButtonValueChanged(app, event)
            if ~app.filterSignalButton.Value
                app.highBandCutoffField.Enable = 'off';
                app.HighbandcutoffHzLabel.Enable = 'off';
                app.lowBandCutoffField.Enable = 'off';
                app.LowbandcutoffHzLabel.Enable = 'off';
            else
                app.highBandCutoffField.Enable = 'on';
                app.HighbandcutoffHzLabel.Enable = 'on';
                app.lowBandCutoffField.Enable = 'on';
                app.LowbandcutoffHzLabel.Enable = 'on';
            end
        end

        % Value changed function: miscellaneousSwitch
        function miscellaneousSwitchValueChanged(app, event)
            if app.miscellaneousSwitch.Value == 'Randomised'
                app.numDismissedAxons.Enable = 'off';
                app.NumberofDismissedAxonsDropDownLabel.Enable = 'off';
                app.numRecruitedAxons.Enable = 'off';
                app.NumberofRecruitedAxonsDropDownLabel.Enable = 'off';
                app.numInflammedAxons.Enable = 'off';
                app.NumberofInflammedAxonsDropDownLabel.Enable = 'off';
            else
                app.numDismissedAxons.Enable = 'on';
                app.NumberofDismissedAxonsDropDownLabel.Enable = 'on';
                app.numRecruitedAxons.Enable = 'on';
                app.NumberofRecruitedAxonsDropDownLabel.Enable = 'on';
                app.numInflammedAxons.Enable = 'on';
                app.NumberofInflammedAxonsDropDownLabel.Enable = 'on';
            end
        end

        % Button pushed function: saveSimulationButton
        function saveSimulationButtonPushed(app, event)
            [file, path] = uiputfile(['simulations' filesep 'sim.mat'], 'Save file name');
            if file     % checks if file name has been set properly
                file_name = [path filesep file];
                simulation = app.simulation;
                save(file_name, 'simulation');
            else
                errordlg('User did not chose a file location. The simulation was not saved.', 'File error');
            end
        end

        % Value changed function: inflammationSwitch
        function inflammationSwitchValueChanged(app, event)
            if app.inflammationSwitch.Value
                app.numInflammedAxons.Enable = 'on';
                app.NumberofInflammedAxonsLabel.Enable = 'on';
                app.inflammationOnset.Enable = 'on';
                app.InflammationOnsetTimesLabel.Enable = 'on';
                app.inflammationTau.Enable = 'on';
                app.TauDecayValuesLabel.Enable = 'on';
            else
                app.numInflammedAxons.Enable = 'off';
                app.NumberofInflammedAxonsLabel.Enable = 'off';
                app.inflammationOnset.Enable = 'off';
                app.InflammationOnsetTimesLabel.Enable = 'off';
                app.inflammationTau.Enable = 'off';
                app.TauDecayValuesLabel.Enable = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.customizableEventsPanel = uipanel(app.UIFigure);
            app.customizableEventsPanel.Title = 'Customizable Events (default randomised)';
            app.TabGroup2 = uitabgroup(app.customizableEventsPanel);
            app.InflammationTab = uitab(app.TabGroup2);
            app.InflammationTab.Title = 'Inflammation';
            app.inflammationSwitch = uiswitch(app.InflammationTab, 'slider');
            app.inflammationSwitch.Items = {'', ' '};
            app.inflammationSwitch.ValueChangedFcn = createCallbackFcn(app, @inflammationSwitchValueChanged, true);
            app.inflammationSwitch.Value = '';
            app.NumberofInflammedAxonsLabel = uilabel(app.InflammationTab);
            app.NumberofInflammedAxonsLabel.Enable = 'off';
            app.NumberofInflammedAxonsLabel.Text = 'Number of Inflammed Axons';
            app.numInflammedAxons = uieditfield(app.InflammationTab, 'numeric');
            app.numInflammedAxons.Enable = 'off';
            app.numInflammedAxons.Value = 5;
            app.TauDecayValuesLabel = uilabel(app.InflammationTab);
            app.TauDecayValuesLabel.Enable = 'off';
            app.TauDecayValuesLabel.Text = 'Tau Decay Value (s)';
            app.inflammationTau = uieditfield(app.InflammationTab, 'numeric');
            app.inflammationTau.Enable = 'off';
            app.inflammationTau.Value = 4e-05;
            app.InflammationOnsetTimesLabel = uilabel(app.InflammationTab);
            app.InflammationOnsetTimesLabel.Enable = 'off';
            app.InflammationOnsetTimesLabel.Text = 'Inflammation Onset Time (s)';
            app.inflammationOnset = uieditfield(app.InflammationTab, 'numeric');
            app.inflammationOnset.Enable = 'off';
            app.inflammationOnset.Value = 1;
            app.EnableLabel = uilabel(app.InflammationTab);
            app.EnableLabel.Text = 'Enable';
            app.DisturbancesTab = uitab(app.TabGroup2);
            app.DisturbancesTab.Title = 'Disturbances';
            app.disturbancesSwitch = uiswitch(app.DisturbancesTab, 'slider');
            app.disturbancesSwitch.Items = {'', ''};
            app.disturbancesSwitch.Value = '';
            app.MiscellaneousTab = uitab(app.TabGroup2);
            app.MiscellaneousTab.Title = 'Miscellaneous';
            app.miscellaneousSwitch = uiswitch(app.MiscellaneousTab, 'slider');
            app.miscellaneousSwitch.Items = {'', ''};
            app.miscellaneousSwitch.ValueChangedFcn = createCallbackFcn(app, @miscellaneousSwitchValueChanged, true);
            app.miscellaneousSwitch.Value = '';
            app.NumberofRecruitedAxonsLabel = uilabel(app.MiscellaneousTab);
            app.NumberofRecruitedAxonsLabel.Text = 'Number of Recruited Axons';
            app.numRecruitedAxons = uieditfield(app.MiscellaneousTab, 'numeric');
            app.numRecruitedAxons.Value = 5;
            app.NumberofDismissedAxonsLabel = uilabel(app.MiscellaneousTab);
            app.NumberofDismissedAxonsLabel.Text = 'Number of Dismissed Axons';
            app.numDismissedAxons = uieditfield(app.MiscellaneousTab, 'numeric');
            app.numDismissedAxons.Value = 5;
            app.AxonsPanel = uipanel(app.UIFigure);
            app.AxonsPanel.Title = 'Axons';
            app.NumberofAxonsLabel = uilabel(app.AxonsPanel);
            app.NumberofAxonsLabel.Text = 'Number of Axons';
            app.numAxonsField = uieditfield(app.AxonsPanel, 'numeric');
            app.numAxonsField.Value = 5;
            app.SamplingRateSamplessLabel = uilabel(app.AxonsPanel);
            app.SamplingRateSamplessLabel.Text = 'Sampling Rate (Samples/s)';
            app.samplingRateField = uieditfield(app.AxonsPanel, 'numeric');
            app.samplingRateField.Value = 5000;
            app.SimulationTimesLabel = uilabel(app.AxonsPanel);
            app.SimulationTimesLabel.Text = 'Simulation Time (s)';
            app.simulationTimeField = uieditfield(app.AxonsPanel, 'numeric');
            app.simulationTimeField.Value = 2000;
            app.MaxPossibleSpikeRatespikessLabel = uilabel(app.AxonsPanel);
            app.MaxPossibleSpikeRatespikessLabel.Text = 'Max Possible Spike Rate (spikes/s)';
            app.maxSpikeRateField = uieditfield(app.AxonsPanel, 'numeric');
            app.maxSpikeRateField.Value = 5;
            app.overlapCheckBox = uicheckbox(app.AxonsPanel);
            app.overlapCheckBox.Text = 'Spikes can overlap';
            app.repeatTemplateCheckBox = uicheckbox(app.AxonsPanel);
            app.repeatTemplateCheckBox.Text = 'Spikes can have the same template';
            app.repeatTemplateCheckBox.Value = true;
            app.RecordingConditionsPanel = uipanel(app.UIFigure);
            app.RecordingConditionsPanel.Title = 'Recording Conditions';
            app.SignalToNoiseRatioLabel = uilabel(app.RecordingConditionsPanel);
            app.SignalToNoiseRatioLabel.Text = 'Signal To Noise Ratio';
            app.SNRField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.SNRField.Value = 20;
            app.addDriftCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.addDriftCheckBox.Text = 'Add Drift';
            app.addDriftCheckBox.Value = true;
            app.addNoiseCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.addNoiseCheckBox.Text = 'Add noise';
            app.addNoiseCheckBox.Value = true;
            app.preNoiseCheckBox = uicheckbox(app.RecordingConditionsPanel);
            app.preNoiseCheckBox.Text = 'Append initial noise';
            app.LowbandcutoffHzLabel = uilabel(app.RecordingConditionsPanel);
            app.LowbandcutoffHzLabel.Text = 'Low band cutoff (Hz) :';
            app.lowBandCutoffField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.lowBandCutoffField.Value = 40;
            app.HighbandcutoffHzLabel = uilabel(app.RecordingConditionsPanel);
            app.HighbandcutoffHzLabel.Text = 'High band cutoff (Hz) :';
            app.highBandCutoffField = uieditfield(app.RecordingConditionsPanel, 'numeric');
            app.highBandCutoffField.Value = 120;
            app.filterSignalButton = uibutton(app.RecordingConditionsPanel, 'state');
            app.filterSignalButton.ValueChangedFcn = createCallbackFcn(app, @filterSignalButtonValueChanged, true);
            app.filterSignalButton.Text = 'Filter Signal';
            app.filterSignalButton.Value = true;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.plotCheckBox = uicheckbox(app.UIFigure);
            app.plotCheckBox.Text = 'Plot results on separate window';
            app.saveSimulationButton = uibutton(app.UIFigure, 'push');
            app.saveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @saveSimulationButtonPushed, true);
            app.saveSimulationButton.Text = 'Save Simulation';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainSimulationTab = uitab(app.TabGroup);
            app.MainSimulationTab.Title = 'Main Simulation';
            app.simulationUIAxes = uiaxes(app.MainSimulationTab);
            title(app.simulationUIAxes, 'Simulation')
            xlabel(app.simulationUIAxes, 'Time (s)')
            ylabel(app.simulationUIAxes, 'Amplitude')
            zlabel(app.simulationUIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end